classdef work5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        DropDown_3       matlab.ui.control.DropDown
        Label_11         matlab.ui.control.Label
        DropDown_2       matlab.ui.control.DropDown
        Label_10         matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        Label_9          matlab.ui.control.Label
        Button_38        matlab.ui.control.Button
        Label_8          matlab.ui.control.Label
        Button_37        matlab.ui.control.Button
        Button_36        matlab.ui.control.Button
        Label_7          matlab.ui.control.Label
        HOGButton        matlab.ui.control.Button
        LBPButton        matlab.ui.control.Button
        Label_6          matlab.ui.control.Label
        Button_33        matlab.ui.control.Button
        sobelButton_2    matlab.ui.control.Button
        prewittButton_2  matlab.ui.control.Button
        robertButton_2   matlab.ui.control.Button
        Label_5          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        Button_27        matlab.ui.control.Button
        Button_26        matlab.ui.control.Button
        Label_3          matlab.ui.control.Label
        Button_25        matlab.ui.control.Button
        Button_24        matlab.ui.control.Button
        Button_23        matlab.ui.control.Button
        Button_22        matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        Label            matlab.ui.control.Label
        Button_19        matlab.ui.control.Button
        Image2           matlab.ui.control.Image
        Image            matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function open_img(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择图像');
            if filename ~= 0
             img = imread(fullfile(pathname, filename));  % 读取图像
             app.Image.ImageSource = img;  % 显示在GUI中的原图区域
            end
        end

        % Button pushed function: Button_36
        function showHistogram(app, event)
            %显示灰度直方图
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            [counts, binLocations] = imhist(gray_img);
            % 创建直方图图形并绘制
            figure('Visible', 'off');
            bar(binLocations, counts, 'BarWidth', 1, 'FaceColor', 'b');
            title('灰度图像直方图');
            xlabel('灰度级');
            ylabel('像素数');
            frame = getframe(gcf);  
            histImage = frame.cdata;  
            close;
            app.Image2.ImageSource = histImage;
        end

        % Button pushed function: Button_37
        function histogramEqualization(app, event)
            %直方图均衡化
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            [counts, ~] = imhist(gray_img);
            cdf = cumsum(counts) / numel(gray_img);
            cdfMin = min(cdf(cdf > 0));
            lookupTable = round((cdf - cdfMin) / (1 - cdfMin) * 255);
            equalizedImg = uint8(lookupTable(double(gray_img) + 1));
            % 将灰度图像转换为RGB图像，复制到三个通道
            equalizedRgbImg = repmat(equalizedImg, [1, 1, 3]);
            app.Image2.ImageSource = equalizedRgbImg;
        end

        % Button pushed function: Button_38
        function histogramMatching(app, event)
            %直方图匹配（规定化）
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            % 加载一张参考图像，用于直方图匹配
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, '选择参考图像');
            if filename == 0
                return;
            end
            refImg = imread(fullfile(pathname, filename));
            refGrayImg = rgb2gray(refImg);
            % 执行直方图匹配
            matchedImg = imhistmatch(gray_img, refGrayImg);
            matchedRgbImg = repmat(matchedImg, [1, 1, 3]);
            app.Image2.ImageSource = matchedRgbImg;
        end

        % Button pushed function: Button_22
        function gray(app, event)
            %灰度化图像
            img = app.Image.ImageSource;
            if ~isempty(img)
                %灰度化
                R = img(:,:,1);  % 红色通道
                G = img(:,:,2);  % 绿色通道
                B = img(:,:,3);  % 蓝色通道
                gray_img = uint8(0.299*double(R) + 0.587*double(G) + 0.114*double(B));
                gray_rgb = repmat(gray_img, [1, 1, 3]);
                app.Image2.ImageSource = gray_rgb;
            end
        end

        % Button pushed function: Button_23
        function linear(app, event)
            %线性变换增强对比度
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            % new_img = a * gray_img + b
            a = 1.5;  % 选择适当的增益
            b = 0;    % 偏移量
            enhanced_img = a * double(gray_img) + b;
            enhanced_img = uint8(min(max(enhanced_img, 0), 255));
            enhanced_img_rgb = repmat(enhanced_img, [1, 1, 3]);
            app.Image2.ImageSource = enhanced_img_rgb;
        end

        % Button pushed function: Button_24
        function log(app, event)
            %对数变换增强对比度
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            c = 255 / log(1 + max(double(gray_img(:))));
            enhanced_img = c * log(1 + double(gray_img));
            enhanced_img = uint8(min(enhanced_img, 255));
            enhanced_img_rgb = repmat(enhanced_img, [1, 1, 3]);
            app.Image2.ImageSource = enhanced_img_rgb;
        end

        % Button pushed function: Button_25
        function exp(app, event)
            %指数变换增强对比度
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            gray_img = rgb2gray(img);
            b = 50;  % 可调节的参数
            c = 255 / (exp(255 / b) - 1);
            enhanced_img = c * (exp(double(gray_img) / b) - 1);
            enhanced_img = uint8(min(enhanced_img, 255));
            enhanced_img_rgb = repmat(enhanced_img, [1, 1, 3]);
            app.Image2.ImageSource = enhanced_img_rgb;
        end

        % Button pushed function: Button_26
        function scale(app, event)
             %缩放变换
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            scaled_img = imresize(img,0.2);
            app.Image2.ImageSource = scaled_img;
        end

        % Button pushed function: Button_27
        function rotate(app, event)
            %旋转变换
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择一张图像!');
                return;
            end
            [original_height, original_width, num_channels] = size(img);
            angle = 90;
            new_height = original_width;
            new_width = original_height;
            rotated_img = zeros(new_height, new_width, num_channels, 'uint8');
            % 旋转矩阵（90度）
            for row = 1:original_height
                for col = 1:original_width
                    new_row = col;
                    new_col = original_height - row + 1;
                    if new_row >= 1 && new_row <= new_height && new_col >= 1 && new_col <= new_width
                        rotated_img(new_row, new_col, :) = img(row, col, :);
                    end
                end
            end
            app.Image2.ImageSource = rotated_img;
        end

        % Button pushed function: robertButton_2
        function robertEdge(app, event)
            %Roberts算子边缘提取
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择图像!');
                return;
            end
            gray_img = rgb2gray(img);
            % 使用 Roberts 算子进行边缘检测
            edge_img = edge(gray_img, 'roberts');
            edge_img_rgb = cat(3, edge_img, edge_img, edge_img);
            edge_img_rgb = im2double(edge_img_rgb);
            app.Image2.ImageSource = edge_img_rgb;
        end

        % Button pushed function: prewittButton_2
        function prewittEdge(app, event)
            %Prewitt算子边缘提取
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择图像!');
                return;
            end
            gray_img = rgb2gray(img);
            %使用Prewitt算子进行边缘检测
            edge_img = edge(gray_img, 'prewitt');
            edge_img_rgb = cat(3, edge_img, edge_img, edge_img);
            edge_img_rgb = im2double(edge_img_rgb);
            app.Image2.ImageSource = edge_img_rgb;
        end

        % Button pushed function: sobelButton_2
        function sobelEdge(app, event)
            %Sobel算子边缘提取
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择图像!');
                return;
            end
            gray_img = rgb2gray(img);
            % Sobel算子
            edge_img = edge(gray_img, 'sobel');
            edge_img_rgb = cat(3, edge_img, edge_img, edge_img);
            edge_img_rgb = im2double(edge_img_rgb);
            app.Image2.ImageSource = edge_img_rgb;
        end

        % Button pushed function: Button_33
        function laplaceEdge(app, event)
            %laplace算子边缘提取
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择图像!');
                return;
            end
            gray_img = rgb2gray(img);
            % laplace算子
            h = fspecial('laplacian', 0.5);
            edge_img = imfilter(gray_img, h);
            edge_img = mat2gray(edge_img);
            edge_img_rgb = cat(3, edge_img, edge_img, edge_img);
            edge_img_rgb = im2double(edge_img_rgb);
            app.Image2.ImageSource = edge_img_rgb;
        end

        % Button pushed function: Button_19
        function target(app, event)
            img = app.Image.ImageSource;
            if isempty(img)
                msgbox('请先选择图像!');
                return;
            end
            gray_img = rgb2gray(img);
            threshold = graythresh(gray_img);
            binaryImage = imbinarize(gray_img, threshold);  % 二值化图像
            se = strel('disk', 5);
            cleanImage = imopen(binaryImage, se);  % 开运算去噪声
            target_img = uint8(cleanImage) * 255;  % 将二值化图像转换为白色物体，黑色背景
            target_img_rgb = cat(3, target_img, target_img, target_img); 
            app.Image2.ImageSource = target_img_rgb;
        end

        % Button pushed function: LBPButton
        function LBP(app, event)
            %LBP特征提取
            img = app.Image.ImageSource;
            % 将图像转换为灰度图像（如果是彩色图像）
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
         
            % 获取图像尺寸
            [rows, cols] = size(img);
         
            % 初始化LBP特征矩阵
            lbpFeatureMap = zeros(rows, cols);
         
            % 定义邻域窗口（3x3窗口，不包括中心像素）
            neighbors = [-1 -1; -1 0; -1 1; 0 -1; 0 1; 1 -1; 1 0; 1 1];
         
            % 遍历图像的每个像素（忽略边缘像素）
            for i = 2:rows-1
                for j = 2:cols-1
                    center = double(img(i, j));
                    binaryPattern = '';
                    for k = 1:size(neighbors, 1)
                        ni = i + neighbors(k, 1);
                        nj = j + neighbors(k, 2);
                        if img(ni, nj) >= center
                            binaryPattern = strcat(binaryPattern, '1');
                        else
                            binaryPattern = strcat(binaryPattern, '0');
                        end
                    end
                    % 将二进制模式转换为十进制数
                    lbpFeatureMap(i, j) = bin2dec(binaryPattern);
                end
            end
            % 显示LBP特征图
            figure;
            imshow(lbpFeatureMap, []);
            title('LBP 特征图');
            %colormap(gray); % 使用灰度颜色映射

        end

        % Button pushed function: HOGButton
        function HOG(app, event)
            %HOG特征提取
            % 获取图像
            img = app.Image.ImageSource;
            img = rgb2gray(img);
            [features, ~] = extractHOGFeatures(img, 'CellSize', [8 8]);
             % 使用条形图展示特征向量
            figure;
            subplot(1,2,1);
            bar(features); 
            title('HOG Feature Vector');
            xlabel('Feature Index');
            ylabel('Feature Value');
            % 使用直方图展示 HOG 特征的分布
            subplot(1,2,2);
            histogram(features, 'BinMethod', 'auto');  % 根据特征向量自动选择合适的 bin 宽度
            title('Histogram of HOG Features');
            xlabel('Feature Value');
            ylabel('Frequency');
        end

        % Drop down opening function: DropDown
        function noise(app, event)
            selectedValue = app.DropDown.Value;
            switch selectedValue
                case '椒盐噪声'
                    img = app.Image.ImageSource;
                    if isempty(img)
                        msgbox('请先选择一张图像!');
                        return;
                    end            
                    prompt = {'输入噪声比例（如0.05表示5%噪声）:'};
                    dlgtitle = '输入噪声参数';
                    dims = [1 35];
                    definput = {'0.05'};
                    noise_strength = str2double(inputdlg(prompt, dlgtitle, dims, definput));
                    [rows, cols, channels] = size(img);
                    num_noise_pixels = round(noise_strength * rows * cols);
                    noisy_img = img; 
                    % 盐噪声：设置部分像素为白色
                    salt_pixels = rand(num_noise_pixels, 2);
                    salt_pixels = round(salt_pixels .* [rows-1, cols-1]) + 1;
                    for k = 1:num_noise_pixels
                        noisy_img(salt_pixels(k, 1), salt_pixels(k, 2), :) = 255;
                    end
                    % 胡椒噪声：设置部分像素为黑色
                    pepper_pixels = rand(num_noise_pixels, 2);
                    pepper_pixels = round(pepper_pixels .* [rows-1, cols-1]) + 1;
                    for k = 1:num_noise_pixels
                        noisy_img(pepper_pixels(k, 1), pepper_pixels(k, 2), :) = 0;
                    end
                    app.Image2.ImageSource = noisy_img;
                    app.Image2.UserData = noisy_img;
                    disp('椒盐噪声添加完成');

                case '高斯噪声'
                    img = app.Image.ImageSource;
                    if isempty(img)
                        msgbox('请先选择一张图像!');
                        return;
                    end
                    prompt = {'输入高斯噪声的均值（如0）:'};
                    dlgtitle = '输入高斯噪声参数';
                    dims = [1 35];
                    definput = {'0'};
                    mean = str2double(inputdlg(prompt, dlgtitle, dims, definput));
                    prompt2 = {'输入高斯噪声的标准差（如0.05）:'};
                    stddev = str2double(inputdlg(prompt2, dlgtitle, dims, definput));    
                    % 生成高斯噪声
                    noisy_img = imnoise(img, 'gaussian', mean, stddev);
                    app.Image2.ImageSource = noisy_img;
                    app.Image2.UserData = noisy_img;
                    disp('高斯噪声添加完成');

                case '泊松噪声'
                    img = app.Image.ImageSource;
                    if isempty(img)
                        msgbox('请先选择一张图像!');
                        return;
                    end 
                    % 生成泊松噪声
                    noisy_img = imnoise(img, 'poisson');
                    app.Image2.ImageSource = noisy_img;
                    app.Image2.UserData = noisy_img;
                    disp('泊松噪声添加完成');

                otherwise
                    % 如果没有匹配的选项，执行默认操作
                    disp('未选中有效选项');
            end
        end

        % Drop down opening function: DropDown_3
        function spitial_filtering(app, event)
            selectedValue = app.DropDown_3.Value;
            switch selectedValue
                case '均值滤波'
                    %空域滤波处理（均值滤波）
                    img = app.Image.ImageSource;
                    if isempty(img)
                        msgbox('请先选择一张图像!');
                        return;
                    end 
                    filtered_img = imfilter(img, fspecial('average', [3 3]));
                    app.Image2.ImageSource = filtered_img;
                    disp('均值滤波完成');

                case '高斯滤波'
                    img = app.Image.ImageSource;
                    if isempty(img)
                        msgbox('请先选择一张图像!');
                        return;
                    end 
                    sigma = 1;  % 高斯滤波器的标准差
                    h = fspecial('gaussian', [3 3], sigma);
                    filtered_img = imfilter(img, h);
                    app.Image2.ImageSource = filtered_img;
                    disp('高斯滤波完成');
              
                otherwise
                    % 如果没有匹配的选项，执行默认操作
                    disp('未选中有效选项');
            end
        end

        % Drop down opening function: DropDown_2
        function frequency_filtering(app, event)
            selectedValue = app.DropDown_2.Value;
            switch selectedValue
                case '理想低通滤波'
                    img = app.Image.ImageSource;
                    if isempty(img)
                        msgbox('请先选择一张图像!');
                        return;
                    end 
                    gray_img = rgb2gray(img);
                    f = fft2(double(gray_img));
                    fshift = fftshift(f);
                    [M, N] = size(fshift);
                    [u, v] = meshgrid(-floor(N/2):floor((N-1)/2), -floor(M/2):floor((M-1)/2));
                    D = sqrt(u.^2 + v.^2);  % 计算频率坐标
                    cutoff = 30;  % 截止频率
                    H = double(D <= cutoff);  % 理想低通滤波器（保留低于截止频率的部分）
                    fshift_filtered = fshift .* H;  % 应用滤波器
                    f_filtered = ifftshift(fshift_filtered);  % 反shift
                    img_filtered = real(ifft2(f_filtered));  % 逆傅里叶变换
                    img_filtered = uint8(mat2gray(img_filtered) * 255);
                    img_rgb = repmat(img_filtered, [1, 1, 3]);
                    app.Image2.ImageSource = img_rgb;
                    disp('理想低通滤波完成');

                case '巴特沃斯低通滤波'
                    img = app.Image.ImageSource;
                    if isempty(img)
                        msgbox('请先选择一张图像!');
                        return;
                    end 
                    gray_img = rgb2gray(img);
                    f = fft2(double(gray_img));
                    fshift = fftshift(f);
                    [M, N] = size(fshift);
                    [u, v] = meshgrid(-floor(N/2):floor((N-1)/2), -floor(M/2):floor((M-1)/2));
                    D = sqrt(u.^2 + v.^2);  % 计算频率坐标
                    cutoff = 30;  % 截止频率
                    n = 2;  % 巴特沃斯滤波器的阶数，通常取值为2或更高
                    H = 1 ./ (1 + (D / cutoff).^(2 * n));  % 巴特沃斯低通滤波器公式
                    fshift_filtered = fshift .* H;  % 应用滤波器
                    f_filtered = ifftshift(fshift_filtered);  % 反shift
                    img_filtered = real(ifft2(f_filtered));  % 逆傅里叶变换
                    img_filtered = uint8(mat2gray(img_filtered) * 255);
                    img_rgb = repmat(img_filtered, [1, 1, 3]);
                    app.Image2.ImageSource = img_rgb;
                    disp('巴特沃斯低通滤波完成');
                
                otherwise
                    % 如果没有匹配的选项，执行默认操作
                    disp('未选中有效选项');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @open_img, true);
            app.Image2 = uiimage(app.UIFigure);
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @target, true);
            app.Button_19.Text = '提取目标';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '原图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '目标图';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @gray, true);
            app.Button_22.Text = '灰度化图像';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @linear, true);
            app.Button_23.Text = '线性变换';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @log, true);
            app.Button_24.Text = '对数变换';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @exp, true);
            app.Button_25.Text = '指数变换';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '灰度图像';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @scale, true);
            app.Button_26.Text = '缩放变换';
            app.Button_27 = uibutton(app.UIFigure, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @rotate, true);
            app.Button_27.Text = '旋转变换';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '图像变换';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '加噪与滤波处理';
            app.robertButton_2 = uibutton(app.UIFigure, 'push');
            app.robertButton_2.ButtonPushedFcn = createCallbackFcn(app, @robertEdge, true);
            app.robertButton_2.Text = 'robert算子';
            app.prewittButton_2 = uibutton(app.UIFigure, 'push');
            app.prewittButton_2.ButtonPushedFcn = createCallbackFcn(app, @prewittEdge, true);
            app.prewittButton_2.Text = 'prewitt算子';
            app.sobelButton_2 = uibutton(app.UIFigure, 'push');
            app.sobelButton_2.ButtonPushedFcn = createCallbackFcn(app, @sobelEdge, true);
            app.sobelButton_2.Text = 'sobel算子';
            app.Button_33 = uibutton(app.UIFigure, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @laplaceEdge, true);
            app.Button_33.Text = '拉普拉斯算子';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '边缘提取';
            app.LBPButton = uibutton(app.UIFigure, 'push');
            app.LBPButton.ButtonPushedFcn = createCallbackFcn(app, @LBP, true);
            app.LBPButton.Text = 'LBP';
            app.HOGButton = uibutton(app.UIFigure, 'push');
            app.HOGButton.ButtonPushedFcn = createCallbackFcn(app, @HOG, true);
            app.HOGButton.Text = 'HOG';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '特征提取';
            app.Button_36 = uibutton(app.UIFigure, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @showHistogram, true);
            app.Button_36.Text = '灰度直方图';
            app.Button_37 = uibutton(app.UIFigure, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @histogramEqualization, true);
            app.Button_37.Text = '直方图均衡化';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '灰度直方';
            app.Button_38 = uibutton(app.UIFigure, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @histogramMatching, true);
            app.Button_38.Text = '直方图匹配';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '图像加噪';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'椒盐噪声', '高斯噪声', '泊松噪声'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @noise, true);
            app.DropDown.Value = '椒盐噪声';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '频域滤波';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'理想低通滤波', '巴特沃斯低通滤波'};
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @frequency_filtering, true);
            app.DropDown_2.Value = '理想低通滤波';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '空域滤波';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'均值滤波', '高斯滤波'};
            app.DropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @spitial_filtering, true);
            app.DropDown_3.Value = '均值滤波';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = work5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end